classdef Stimulator_Gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExperimentPanel                 matlab.ui.container.Panel
        MouseIDEditField                matlab.ui.control.EditField
        MouseIDEditFieldLabel           matlab.ui.control.Label
        ITIsEditField                   matlab.ui.control.NumericEditField
        ITIsLabel                       matlab.ui.control.Label
        DirectorytosaveoutputEditField  matlab.ui.control.EditField
        DirectorytosaveoutputEditFieldLabel  matlab.ui.control.Label
        TrialsEditField                 matlab.ui.control.NumericEditField
        TrialsEditFieldLabel            matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        StimulusPanel                   matlab.ui.container.Panel
        HzSpinner                       matlab.ui.control.Spinner
        HzSpinnerLabel                  matlab.ui.control.Label
        VoltageSpinner                  matlab.ui.control.Spinner
        VoltageSpinnerLabel             matlab.ui.control.Label
        PreLoadStimuliButton            matlab.ui.control.Button
        ofstepsSpinner_2                matlab.ui.control.Spinner
        ofstepsSpinner_2Label           matlab.ui.control.Label
        MaxHzSpinner                    matlab.ui.control.Spinner
        MaxHzSpinnerLabel               matlab.ui.control.Label
        MinHzSpinner                    matlab.ui.control.Spinner
        MinHzSpinnerLabel               matlab.ui.control.Label
        ofstepsSpinner                  matlab.ui.control.Spinner
        ofstepsSpinnerLabel             matlab.ui.control.Label
        MaxVSpinner                     matlab.ui.control.Spinner
        MaxVSpinnerLabel                matlab.ui.control.Label
        MaxSSpinner                     matlab.ui.control.Spinner
        MaxSSpinnerLabel                matlab.ui.control.Label
        MinSSpinner                     matlab.ui.control.Spinner
        MinSSpinnerLabel                matlab.ui.control.Label
        SecondsSpinner                  matlab.ui.control.Spinner
        SecondsSpinnerLabel             matlab.ui.control.Label
        DurationSwitch                  matlab.ui.control.ToggleSwitch
        DurationSwitchLabel             matlab.ui.control.Label
        FrequencySwitch                 matlab.ui.control.ToggleSwitch
        FrequencySwitchLabel            matlab.ui.control.Label
        MinVSpinner                     matlab.ui.control.Spinner
        MinVSpinnerLabel                matlab.ui.control.Label
        AmplitudeSwitch                 matlab.ui.control.ToggleSwitch
        AmplitudeSwitchLabel            matlab.ui.control.Label
        MouseForepawStimulatorv10Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Local variable descriptions
        savedir = pwd;
        mouseID = 0;
        sessionName = "Ca-Imaging";
        outData = [];
        
        % NI-DAQ Parameters
        detection_handle = []; %variable for channels of handle and lick
        TTL_Times = []; %variable to hold TTL recieved times
        TTL_recieved = 0; %boolean flag to indicate a TTL was recieved
        CH_state = 0;
        piezoDriver = [];
        lh = [];
        
        % Stimulus Parameters

        FS = 10000;
        stim_dur = 1; %app.SecondsSpinner.Value;
        stim_durs = NaN; 
        stim_dur_min = NaN; %app.MinSSpinner.Value
        stim_dur_max = NaN; %app.MaxSSpinner.Value;
        all_durs = NaN;
        
        stim_amp = NaN; %app.VoltageSlider.Value;
        stim_amp_min = NaN; %app.MinVSpinner.Value;
        stim_amp_max = NaN; %app.MaxVSpinner.Value;
        stim_amp_steps = NaN; %number of amplitude steps
        stim_amps = NaN; %array of possible amplitude values
        all_amps = NaN;
        
        stim_freq = NaN; %app.HzSlider.Value; %constant frequency value
        stim_freq_min = NaN; %app.MinHzSpinner.Value; %min frequency value
        stim_freq_max = NaN; %app.MaxHzSpinner.Value; %max frequency value
        stim_freq_steps = NaN; %app.ofstepsSpinner_2.Value; %number of frequency steps
        stim_freqs = NaN;% array of possible frequency values
        all_freqs = NaN;
        
        num_trials = NaN;
        stim_space = NaN;
        iti = NaN; %inter-trial interval
        stim_times = NaN; %stim ON times
        stop_flag = 0; %off, proceed
        
    end
    
    methods (Access = public)
        
        function TTL_detection(app, ~, event)
            
            %extracting data from NI
            chData = event.Data(:,1);
            
            %determining changes in signals
            d_chData = diff(chData);
            
            %getting the timing of events 
            check_up = find(d_chData > 3, 1);
            check_dn = find(d_chData < -3, 1);
            
            % find TTL events
            if ~isempty(check_up)
                if ~isempty(check_dn)
                    if abs(check_dn - check_up) < 5
                        %not a true TTL pulse, just noise.
                        %disp("empty");
                    else
                        disp("Pulse detected!");
                        app.TTL_Times = cat(1,app.TTL_Times,GetSecs);
                        app.TTL_recieved = 1;
                        app.CH_state = 1;
                    end
                else
                    disp("Pulse detected!");
                    app.TTL_Times = cat(1,app.TTL_Times,GetSecs);
                    app.TTL_recieved = 1;
                    app.CH_state = 1;
                end
            else
                app.CH_state = 0;
            end  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            selpath = uigetdir;
            app.savedir = selpath;
        end

        % Value changed function: DirectorytosaveoutputEditField
        function DirectorytosaveoutputEditFieldValueChanged(app, event)
            value = app.DirectorytosaveoutputEditField.Value;
            %User clicked in the directory input
            
        end

        % Button pushed function: PreLoadStimuliButton
        function PreLoadStimuliButtonPushed(app, event)
            % Create the piezo stimulus file
            % Get local copies of variables
            app.FS = 10000;
            app.stim_dur = app.SecondsSpinner.Value * 1000; %constant duration value
            app.stim_durs = NaN; %array of randomized variable duration values in ms
            app.stim_dur_min = app.MinSSpinner.Value * 1000; %min stim duration in ms
            app.stim_dur_max = app.MaxSSpinner.Value * 1000; %max stim duration in ms
            
            app.stim_amp = app.VoltageSpinner.Value; %constant amplitude value
            app.stim_amp_min = app.MinVSpinner.Value; %min amplitude value
            app.stim_amp_max = app.MaxVSpinner.Value; %max amplitude value
            app.stim_amp_steps = app.ofstepsSpinner.Value; %number of amplitude steps
            app.stim_amps = linspace(app.stim_amp_min,app.stim_amp_max,app.stim_amp_steps); %array of possible amplitude values
            
            app.stim_freq = app.HzSpinner.Value;; %constant frequency value
            app.stim_freq_min = app.MinHzSpinner.Value; %min frequency value
            app.stim_freq_max = app.MaxHzSpinner.Value; %max frequency value
            app.stim_freq_steps = app.ofstepsSpinner_2.Value; %number of frequency steps
            app.stim_freqs = linspace(app.stim_freq_min, app.stim_freq_max, app.stim_freq_steps); % array of possible frequency values
            
          %app.stim_freq = linspace(app.MinHzSpinner.Value,app.MaxHzSpinner.Value,app.ofstepsSpinner_2.Value);
            app.num_trials = app.TrialsEditField.Value;
            
            %set up outputs
            app.all_durs = zeros(app.num_trials,1);
            app.all_freqs = zeros(app.num_trials,1);
            app.all_amps = zeros(app.num_trials,1);
            app.stim_times = zeros(app.num_trials,1);
            
            app.stim_space = cell(app.num_trials,1);
            if strcmp(app.DurationSwitch.Value,"Random") %random duration
                %app.stim_space = cell(app.num_trials,1);
                app.stim_durs = randi([app.stim_dur_min,app.stim_dur_max],app.num_trials,1);
                app.all_durs = app.stim_durs;
                for ii = 1 : app.num_trials
                    %trial_length = randi([app.stim_dur_min*1000,app.stim_dur_max*1000],1); %random stim duration per trial in ms
                    %t = 0:1/app.FS:app.durs(ii)/1000 - 1/app.FS;
                    app.stim_space{ii} = zeros(app.stim_durs(ii)/1000*app.FS,1);
                end
            else %constant duration
                %app.stim_space = cell(app.num_trials,1);
                %t = 0:1/app.FS:app.stim_dur - 1/app.FS;
                for ii = 1 : app.num_trials
                    app.stim_space{ii,1} = zeros(app.stim_dur/1000*app.FS,1);
                    app.all_durs(ii) = app.stim_dur;
                end
            end
            
            if strcmp(app.FrequencySwitch.Value,"Random") %random frequency
                %trial_freqs = linspace(app.stim_freq_min, app.stim_freq_max, app.stim_freq_steps);
                if strcmp(app.DurationSwitch.Value,"Random") %random duration
                    for ii = 1 : app.num_trials
                        loc_freq = app.stim_freqs(randi(length(app.stim_freqs)));
                        loc_t = 0:1/app.FS:app.stim_durs(ii)/1000 - 1/app.FS;
                        app.stim_space{ii,1} = sin(2*pi*loc_t*loc_freq);
                        app.all_freqs(ii,1) = loc_freq;
                    end
                else %constant duration
                    loc_t = 0:1/app.FS:app.stim_dur/1000 - 1/app.FS;
                    for ii = 1 : app.num_trials
                        loc_freq = app.stim_freqs(randi(length(app.stim_freqs)));
                        app.stim_space{ii,1} = sin(2*pi*loc_t*loc_freq);
                        app.all_freqs(ii,1) = loc_freq;
                    end
                end
                
            else %constant frequency
                if strcmp(app.DurationSwitch.Value,"Random") %random duration
                    for ii = 1 : app.num_trials
                        %loc_freq = app.stim_freqs(randi(length(app.stim_freqs)));
                        loc_t = 0:1/app.FS:app.stim_durs(ii)/1000 - 1/app.FS;
                        app.stim_space{ii,1} = sin(2*pi*loc_t*app.stim_freq);
                        app.all_freqs(ii,1) = app.stim_freq;
                    end
                else %constant duration
                    loc_t = 0:1/app.FS:app.stim_dur/1000 - 1/app.FS;
                    for ii = 1 : app.num_trials
                        app.stim_space{ii,1} = sin(2*pi*loc_t*app.stim_freq);
                        app.all_freqs(ii,1) = app.stim_freq;
                    end
                end
                
            end
            
            
            if strcmp(app.AmplitudeSwitch.Value,"Random") %random amplitude
                for ii = 1 : app.num_trials
                    loc_amp = app.stim_amps(randi(app.stim_amp_steps));
                    app.stim_space{ii,1} = app.stim_space{ii,1} .* loc_amp;
                    app.all_amps(ii) = loc_amp;
                end
            else %constant amplitude
                for ii = 1 : app.num_trials
                    app.stim_space{ii,1} = app.stim_space{ii,1} .* app.stim_amp;
                    app.all_amps(ii) = app.stim_amp;
                end
            end

            
            disp("Stimulus file loaded successfully...")
            disp(strcat("Num_trials = ",num2str(app.num_trials)));
            disp(strcat("Stim_durs = ",num2str(app.all_durs)));
            disp(strcat("Stim_freqs = ",num2str(app.all_freqs)));
            disp(strcat("Stim_amps = ",num2str(app.all_amps)));
            
            app.PreLoadStimuliButton.BackgroundColor = [0, 1, 0];
        end

        % Value changed function: DurationSwitch
        function DurationSwitchValueChanged(app, event)
            value = app.DurationSwitch.Value;
            app.PreLoadStimuliButton.BackgroundColor = [1, 1, 1];
        end

        % Value changed function: AmplitudeSwitch
        function AmplitudeSwitchValueChanged(app, event)
            value = app.AmplitudeSwitch.Value;
            app.PreLoadStimuliButton.BackgroundColor = [1, 1, 1];
        end

        % Value changed function: FrequencySwitch
        function FrequencySwitchValueChanged(app, event)
            value = app.FrequencySwitch.Value;
            app.PreLoadStimuliButton.BackgroundColor = [1, 1, 1];
        end

        % Value changed function: TrialsEditField
        function TrialsEditFieldValueChanged(app, event)
            value = app.TrialsEditField.Value;
            app.PreLoadStimuliButton.BackgroundColor = [1, 1, 1];
        end

        % Callback function
        function VoltageSliderValueChanged(app, event)
            %REPLACE THIS WITH A NUM INPUT
            value = app.VoltageSlider.Value;
            app.PreLoadStimuliButton.BackgroundColor = [1, 1, 1];
        end

        % Callback function
        function HzSliderValueChanged(app, event)
            %REPLACE THIS WITH NUM INPUT
            value = app.HzSlider.Value;
            app.PreLoadStimuliButton.BackgroundColor = [1, 1, 1];
        end

        % Value changed function: ofstepsSpinner
        function ofstepsSpinnerValueChanged(app, event)
            value = app.ofstepsSpinner.Value;
            app.PreLoadStimuliButton.BackgroundColor = [1, 1, 1];
        end

        % Value changed function: ofstepsSpinner_2
        function ofstepsSpinner_2ValueChanged(app, event)
            value = app.ofstepsSpinner_2.Value;
            app.PreLoadStimuliButton.BackgroundColor = [1, 1, 1];
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %check if data has been pre-loaded,
            %if not, then load data.
            % TODO
            
            %Creating output file
            %Output the saved data (out_times and out_events)
            working_dir = pwd; %current directory pre-save
            app.outData = [];
            app.TTL_recieved = 0;
            
            %find correct folder to save data into
            try
                cd(app.savedir); %should be specified earlier in GUI.
            catch
                disp('No mouse directory specified - saving file to Desktop...');
                app.savedir = 'C:\Users\ramirezlab\Desktop';
                cd(app.savedir);
            end
            
            %-----------------
            %see if mouse directory exists
            app.mouseID = num2str(app.MouseIDEditField.Value);
            if isfolder([working_dir '\' app.mouseID]) ~= 1
                mkdir([working_dir '\' app.mouseID])
            else
            end
            
            currDate = datestr(date,2);
            currDate(strfind(currDate,'/')) = '_';
            
            if isfolder([working_dir '\' app.mouseID '\' currDate]) ~= 1
                mkdir([working_dir '\' app.mouseID '\' currDate]);
            else
            end
            
            curDirFile = dir([working_dir '\' app.mouseID '\' currDate]);
            curDirFile = {curDirFile.name};
            idx_file = 0;
            for ff = 1 : length(curDirFile)
                tempFile = curDirFile{ff};
                if contains(tempFile,[app.mouseID '_' app.sessionName])
                    idx_file = idx_file + 1;
                else
                end
            end
            
            if idx_file < 10
                idx_file = ['00' num2str(idx_file)];
            elseif idx_file >= 10 && idx_file < 100
                idx_file = ['0' num2str(idx_file)];
            else
                idx_file = num2str(idx_file);
            end
            
            % Combine Data
            app.outData{1,1} = app.all_durs;
            app.outData{1,2} = app.all_freqs;
            app.outData{1,3} = app.all_amps;
            app.outData{1,4} = app.stim_times;
            app.outData{1,5} = app.TTL_Times;
            
            %quick save
            Data = app.outData;
            fn = strcat(working_dir, '\', app.mouseID, '\', currDate, '\', app.mouseID, '_', app.sessionName, '_', idx_file);
            save(fn, 'Data');
            
            %start
            app.iti = app.ITIsEditField.Value;
            app.stop_flag = 0;
            app.stim_times = zeros(app.num_trials,1);
            
            %set up piezo channels
            app.piezoDriver = daq.createSession('ni');
            addAnalogOutputChannel(app.piezoDriver, 'dev1', 0, 'Voltage');
            app.piezoDriver.Rate = app.FS; %sampling rate
            
            %set up listener channels
            app.detection_handle = daq.createSession('ni');
            addAnalogInputChannel(app.detection_handle,'Dev1', 0 , 'Voltage'); %spout detection
            app.detection_handle.Rate = 10000;
            app.detection_handle.IsContinuous = true;
            app.detection_handle.IsNotifyWhenDataAvailableExceedsAuto = true;
            app.detection_handle.NotifyWhenDataAvailableExceeds = 1000;
            
            %start listener
            disp('Starting listener');
            app.lh = addlistener(app.detection_handle,'DataAvailable', @(src,event) TTL_detection(app, src, event));
            pause(.1);
            disp('Starting collection of data in the background');
            startBackground(app.detection_handle);
            
            %Wait for TTL
            %uncomment for TTL que (from 2P)
%             GoAhead = 0;
%             disp("Experiment Queued, waiting for start pulse...")
%             while GoAhead == 0
%                 pause(0);
%                 if app.TTL_recieved == 1
%                     disp("TTL Recieved ... Starting Experiment!");
%                     app.TTL_Times
%                     GoAhead = 1;
%                     break;
%                 elseif app.stop_flag == 1
%                     GoAhead = 1;
%                     break;
%                 end
%             
%             end
            
            for n=1:app.num_trials
                % Check for stop flag
                if app.stop_flag == 1
                    disp("Experiment Stopped!")
                    break;
                end
                
                % Display trial parameters
                disp(strcat("TRIAL ",num2str(n)," START."));
                pause(0.5);
                disp(strcat("Dur = ",num2str(app.all_durs(n)),"ms. Freq = ",num2str(app.all_freqs(n)),"hz. Amp = ",num2str(app.all_amps(n)),"v."));
                pause(0.5);
                
                % Deliver Stim
                disp("Delivering Stim...");
                app.piezoDriver.queueOutputData(app.stim_space{n,1}'); %que stim
                app.stim_times(n,1) = GetSecs;
                app.piezoDriver.startForeground(); %deliver stim
                
                % Combine Data
                app.outData{1,1} = app.all_durs;
                app.outData{1,2} = app.all_freqs;
                app.outData{1,3} = app.all_amps;
                app.outData{1,4} = app.stim_times;
                app.outData{1,5} = app.TTL_Times;
            
                % save the cell variable holding all the information on a trial by trial basis
                Data = app.outData;
                fn = strcat(working_dir, '\', app.mouseID, '\', currDate, '\', app.mouseID, '_', app.sessionName, '_', idx_file);
                save(fn, 'Data');
                
                pause(app.iti);
                
            end
            
        end

        % Value changed function: ITIsEditField
        function ITIsEditFieldValueChanged(app, event)
            value = app.ITIsEditField.Value;
            app.iti = value;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stop_flag = 1;
            disp("stop cmd recieved");
        end

        % Value changed function: VoltageSpinner
        function VoltageSpinnerValueChanged(app, event)
            value = app.VoltageSpinner.Value;
            app.PreLoadStimuliButton.BackgroundColor = [1, 1, 1];
        end

        % Value changed function: MinSSpinner
        function MinSSpinnerValueChanged(app, event)
            value = app.MinSSpinner.Value;
            app.PreLoadStimuliButton.BackgroundColor = [1, 1, 1];
        end

        % Value changed function: MaxSSpinner
        function MaxSSpinnerValueChanged(app, event)
            value = app.MaxSSpinner.Value;
            app.PreLoadStimuliButton.BackgroundColor = [1, 1, 1];
        end

        % Value changed function: HzSpinner
        function HzSpinnerValueChanged(app, event)
            value = app.HzSpinner.Value;
            app.PreLoadStimuliButton.BackgroundColor = [1, 1, 1];
        end

        % Value changed function: MouseIDEditField
        function MouseIDEditFieldValueChanged(app, event)
            value = app.MouseIDEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MouseForepawStimulatorv10Label = uilabel(app.UIFigure);
            app.MouseForepawStimulatorv10Label.Text = 'Mouse Forepaw Stimulator v1.0';
            app.StimulusPanel = uipanel(app.UIFigure);
            app.StimulusPanel.Title = 'Stimulus';
            app.AmplitudeSwitchLabel = uilabel(app.StimulusPanel);
            app.AmplitudeSwitchLabel.Text = 'Amplitude';
            app.AmplitudeSwitch = uiswitch(app.StimulusPanel, 'toggle');
            app.AmplitudeSwitch.Items = {'Random', 'Uniform'};
            app.AmplitudeSwitch.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSwitchValueChanged, true);
            app.AmplitudeSwitch.Value = 'Uniform';
            app.MinVSpinnerLabel = uilabel(app.StimulusPanel);
            app.MinVSpinnerLabel.Text = 'Min. (V)';
            app.MinVSpinner = uispinner(app.StimulusPanel);
            app.MinVSpinner.Limits = [0 Inf];
            app.MinVSpinner.Value = 0.1;
            app.FrequencySwitchLabel = uilabel(app.StimulusPanel);
            app.FrequencySwitchLabel.Text = 'Frequency';
            app.FrequencySwitch = uiswitch(app.StimulusPanel, 'toggle');
            app.FrequencySwitch.Items = {'Random', 'Uniform'};
            app.FrequencySwitch.ValueChangedFcn = createCallbackFcn(app, @FrequencySwitchValueChanged, true);
            app.FrequencySwitch.Value = 'Random';
            app.DurationSwitchLabel = uilabel(app.StimulusPanel);
            app.DurationSwitchLabel.Text = 'Duration';
            app.DurationSwitch = uiswitch(app.StimulusPanel, 'toggle');
            app.DurationSwitch.Items = {'Random', 'Uniform'};
            app.DurationSwitch.ValueChangedFcn = createCallbackFcn(app, @DurationSwitchValueChanged, true);
            app.DurationSwitch.Value = 'Uniform';
            app.SecondsSpinnerLabel = uilabel(app.StimulusPanel);
            app.SecondsSpinnerLabel.Text = 'Seconds';
            app.SecondsSpinner = uispinner(app.StimulusPanel);
            app.SecondsSpinner.Limits = [0.001 Inf];
            app.SecondsSpinner.Value = 1;
            app.MinSSpinnerLabel = uilabel(app.StimulusPanel);
            app.MinSSpinnerLabel.Text = 'Min. (S)';
            app.MinSSpinner = uispinner(app.StimulusPanel);
            app.MinSSpinner.Limits = [0 Inf];
            app.MinSSpinner.ValueChangedFcn = createCallbackFcn(app, @MinSSpinnerValueChanged, true);
            app.MinSSpinner.Value = 1;
            app.MaxSSpinnerLabel = uilabel(app.StimulusPanel);
            app.MaxSSpinnerLabel.Text = {'Max. (S)'; ''};
            app.MaxSSpinner = uispinner(app.StimulusPanel);
            app.MaxSSpinner.Limits = [0 Inf];
            app.MaxSSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxSSpinnerValueChanged, true);
            app.MaxSSpinner.Value = 2;
            app.MaxVSpinnerLabel = uilabel(app.StimulusPanel);
            app.MaxVSpinnerLabel.Text = 'Max. (V)';
            app.MaxVSpinner = uispinner(app.StimulusPanel);
            app.MaxVSpinner.Limits = [0 Inf];
            app.MaxVSpinner.Value = 3;
            app.ofstepsSpinnerLabel = uilabel(app.StimulusPanel);
            app.ofstepsSpinnerLabel.Text = '# of steps';
            app.ofstepsSpinner = uispinner(app.StimulusPanel);
            app.ofstepsSpinner.Limits = [1 Inf];
            app.ofstepsSpinner.ValueChangedFcn = createCallbackFcn(app, @ofstepsSpinnerValueChanged, true);
            app.ofstepsSpinner.Value = 9;
            app.MinHzSpinnerLabel = uilabel(app.StimulusPanel);
            app.MinHzSpinnerLabel.Text = 'Min. (Hz)';
            app.MinHzSpinner = uispinner(app.StimulusPanel);
            app.MinHzSpinner.Limits = [0 Inf];
            app.MinHzSpinner.Value = 100;
            app.MaxHzSpinnerLabel = uilabel(app.StimulusPanel);
            app.MaxHzSpinnerLabel.Text = 'Max. (Hz)';
            app.MaxHzSpinner = uispinner(app.StimulusPanel);
            app.MaxHzSpinner.Limits = [1 Inf];
            app.MaxHzSpinner.Value = 1200;
            app.ofstepsSpinner_2Label = uilabel(app.StimulusPanel);
            app.ofstepsSpinner_2Label.Text = '# of steps';
            app.ofstepsSpinner_2 = uispinner(app.StimulusPanel);
            app.ofstepsSpinner_2.Limits = [0 Inf];
            app.ofstepsSpinner_2.ValueChangedFcn = createCallbackFcn(app, @ofstepsSpinner_2ValueChanged, true);
            app.ofstepsSpinner_2.Value = 12;
            app.PreLoadStimuliButton = uibutton(app.StimulusPanel, 'push');
            app.PreLoadStimuliButton.ButtonPushedFcn = createCallbackFcn(app, @PreLoadStimuliButtonPushed, true);
            app.PreLoadStimuliButton.Text = {'Pre-Load '; 'Stimuli'};
            app.VoltageSpinnerLabel = uilabel(app.StimulusPanel);
            app.VoltageSpinnerLabel.Text = 'Voltage';
            app.VoltageSpinner = uispinner(app.StimulusPanel);
            app.VoltageSpinner.Step = 0.1;
            app.VoltageSpinner.Limits = [0.1 3];
            app.VoltageSpinner.ValueChangedFcn = createCallbackFcn(app, @VoltageSpinnerValueChanged, true);
            app.VoltageSpinner.Value = 1;
            app.HzSpinnerLabel = uilabel(app.StimulusPanel);
            app.HzSpinnerLabel.Text = 'Hz';
            app.HzSpinner = uispinner(app.StimulusPanel);
            app.HzSpinner.ValueChangedFcn = createCallbackFcn(app, @HzSpinnerValueChanged, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = {'Stop'; ''};
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.ExperimentPanel = uipanel(app.UIFigure);
            app.ExperimentPanel.Title = 'Experiment';
            app.BrowseButton = uibutton(app.ExperimentPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = {'Browse...'; ''};
            app.TrialsEditFieldLabel = uilabel(app.ExperimentPanel);
            app.TrialsEditFieldLabel.Text = '# Trials';
            app.TrialsEditField = uieditfield(app.ExperimentPanel, 'numeric');
            app.TrialsEditField.Limits = [1 Inf];
            app.TrialsEditField.ValueChangedFcn = createCallbackFcn(app, @TrialsEditFieldValueChanged, true);
            app.TrialsEditField.Value = 10;
            app.DirectorytosaveoutputEditFieldLabel = uilabel(app.ExperimentPanel);
            app.DirectorytosaveoutputEditFieldLabel.Text = {'Directory to save output'; ''};
            app.DirectorytosaveoutputEditField = uieditfield(app.ExperimentPanel, 'text');
            app.DirectorytosaveoutputEditField.ValueChangedFcn = createCallbackFcn(app, @DirectorytosaveoutputEditFieldValueChanged, true);
            app.DirectorytosaveoutputEditField.Value = '"Enter Filepath"';
            app.ITIsLabel = uilabel(app.ExperimentPanel);
            app.ITIsLabel.Text = 'ITI (s)';
            app.ITIsEditField = uieditfield(app.ExperimentPanel, 'numeric');
            app.ITIsEditField.Limits = [0 Inf];
            app.ITIsEditField.ValueChangedFcn = createCallbackFcn(app, @ITIsEditFieldValueChanged, true);
            app.ITIsEditField.Value = 1;
            app.MouseIDEditFieldLabel = uilabel(app.ExperimentPanel);
            app.MouseIDEditFieldLabel.Text = 'Mouse ID';
            app.MouseIDEditField = uieditfield(app.ExperimentPanel, 'text');
            app.MouseIDEditField.ValueChangedFcn = createCallbackFcn(app, @MouseIDEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Stimulator_Gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end